classdef ControlGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WISColdRydbergUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LasersandAOMsTab                matlab.ui.container.Tab
        MOTButton                       matlab.ui.control.StateButton
        RepumpButton                    matlab.ui.control.StateButton
        DepumpButton                    matlab.ui.control.StateButton
        MOT1PowermWGaugeLabel           matlab.ui.control.Label
        MOT1PowermWGauge                matlab.ui.control.Gauge
        MOT2PowermWGaugeLabel           matlab.ui.control.Label
        MOT2PowermWGauge                matlab.ui.control.Gauge
        MOT3PowermWGaugeLabel           matlab.ui.control.Label
        MOT3PowermWGauge                matlab.ui.control.Gauge
        ControlButton                   matlab.ui.control.StateButton
        ProbeButton                     matlab.ui.control.StateButton
        UITable2                        matlab.ui.control.Table
        AOM1VCOFreqSliderLabel          matlab.ui.control.Label
        AOM1VCOFreqSlider               matlab.ui.control.Slider
        AOM2VCOFreqSliderLabel          matlab.ui.control.Label
        AOM2VCOFreqSlider               matlab.ui.control.Slider
        AOM3VCOFreqSliderLabel          matlab.ui.control.Label
        AOM3VCOFreqSlider               matlab.ui.control.Slider
        AOM1TTLSwitchLabel              matlab.ui.control.Label
        AOM1TTLSwitch                   matlab.ui.control.Switch
        AOM2TTLSwitchLabel              matlab.ui.control.Label
        AOM2TTLSwitch                   matlab.ui.control.Switch
        AOM3TTLSwitchLabel              matlab.ui.control.Label
        AOM3TTLSwitch                   matlab.ui.control.Switch
        AOM1freq                        matlab.ui.control.Label
        AOM2freq                        matlab.ui.control.Label
        AOM3freq                        matlab.ui.control.Label
        DipoleTrapButton                matlab.ui.control.StateButton
        TAONButton                      matlab.ui.control.StateButton
        ICEonlineLamp_2Label            matlab.ui.control.Label
        ICEonlineLamp_2                 matlab.ui.control.Lamp
        ElectromagnetsTab               matlab.ui.container.Tab
        CircularCoilCurrentSliderLabel  matlab.ui.control.Label
        CircularCoilCurrentSlider       matlab.ui.control.Slider
        RectangularCoilCurrentSliderLabel  matlab.ui.control.Label
        RectangularCoilCurrentSlider    matlab.ui.control.Slider
        CicularCoilOffSwitchLabel       matlab.ui.control.Label
        CicularCoilOffSwitch            matlab.ui.control.Switch
        RectangularCoilOffSwitchLabel   matlab.ui.control.Label
        RectangularCoilOffSwitch        matlab.ui.control.Switch
        Snubberchannel1SwitchLabel      matlab.ui.control.Label
        Snubberchannel1Switch           matlab.ui.control.ToggleSwitch
        Snubberchannel2SwitchLabel      matlab.ui.control.Label
        Snubberchannel2Switch           matlab.ui.control.ToggleSwitch
        BiascoilpairXcurrentLabel       matlab.ui.control.Label
        CircularCoilCurrentSlider_2     matlab.ui.control.Slider
        BiascoilpairYcurrentLabel       matlab.ui.control.Label
        CircularCoilCurrentSlider_3     matlab.ui.control.Slider
        BiascoilpairZcurrentLabel       matlab.ui.control.Label
        CircularCoilCurrentSlider_4     matlab.ui.control.Slider
        SequenceBuilderTab              matlab.ui.container.Tab
        SequenceListBox                 matlab.ui.control.ListBox
        ClearallButton                  matlab.ui.control.Button
        LoadSequenceButton              matlab.ui.control.Button
        SaveSequenceButton              matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        AddpulseButton                  matlab.ui.control.Button
        SanityCheckButton               matlab.ui.control.Button
        FPGAstatusLabel                 matlab.ui.control.Label
        FPGAConnectionStatusBox         matlab.ui.control.TextArea
        FPGAuploadIndicatorLamp         matlab.ui.control.Lamp
        StatusTab                       matlab.ui.container.Tab
        CoilcoolingwaterflowLampLabel   matlab.ui.control.Label
        CoilcoolingwaterflowLamp        matlab.ui.control.Lamp
        CoilsupplystatusLampLabel       matlab.ui.control.Label
        CoilsupplystatusLamp            matlab.ui.control.Lamp
        coiltemperatureOKLampLabel      matlab.ui.control.Label
        coiltemperatureOKLamp           matlab.ui.control.Lamp
        TAONLampLabel                   matlab.ui.control.Label
        TAONLamp                        matlab.ui.control.Lamp
        MasterLockedLampLabel           matlab.ui.control.Label
        MasterLockedLamp                matlab.ui.control.Lamp
        MOTLaserLOCKEDLampLabel         matlab.ui.control.Label
        MOTLaserLOCKEDLamp              matlab.ui.control.Lamp
        RepumpLaserLOCKEDLampLabel      matlab.ui.control.Label
        RepumpLaserLOCKEDLamp           matlab.ui.control.Lamp
        DepumpLaserLOCKEDLampLabel      matlab.ui.control.Label
        DepumpLaserLOCKEDLamp           matlab.ui.control.Lamp
        ImagingLaserLOCKEDLampLabel     matlab.ui.control.Label
        ImagingLaserLOCKEDLamp          matlab.ui.control.Lamp
        TiSaphLOCKEDLampLabel           matlab.ui.control.Label
        TiSaphLOCKEDLamp                matlab.ui.control.Lamp
        CombonlineLampLabel             matlab.ui.control.Label
        CombonlineLamp                  matlab.ui.control.Lamp
        StatusmonitorarduinoConnectedLampLabel  matlab.ui.control.Label
        StatusmonitorarduinoConnectedLamp  matlab.ui.control.Lamp
        WavelengthmeteronlineLampLabel  matlab.ui.control.Label
        WavelengthmeteronlineLamp       matlab.ui.control.Lamp
        ConfigureerrorreportingButton   matlab.ui.control.Button
        ICEonlineLampLabel              matlab.ui.control.Label
        ICEonlineLamp                   matlab.ui.control.Lamp
        TiSaphOnlineLampLabel           matlab.ui.control.Label
        TiSaphOnlineLamp                matlab.ui.control.Lamp
        TATempcontrolleronLampLabel     matlab.ui.control.Label
        TATempcontrolleronLamp          matlab.ui.control.Lamp
        DBR1TempLockedLampLabel         matlab.ui.control.Label
        DBR1TempLockedLamp              matlab.ui.control.Lamp
        DispensersTab                   matlab.ui.container.Tab
        Dispenser1CurrentSliderLabel    matlab.ui.control.Label
        Dispenser1CurrentSlider         matlab.ui.control.Slider
        Dispenser2CurrentSliderLabel    matlab.ui.control.Label
        Dispenser2CurrentSlider         matlab.ui.control.Slider
        Dispenser3CurrentSliderLabel    matlab.ui.control.Label
        Dispenser3CurrentSlider         matlab.ui.control.Slider
        Dispenser4CurrentSliderLabel    matlab.ui.control.Label
        Dispenser4CurrentSlider         matlab.ui.control.Slider
        Dispenser5CurrentSliderLabel    matlab.ui.control.Label
        Dispenser5CurrentSlider         matlab.ui.control.Slider
        Dispenser6CurrentSliderLabel    matlab.ui.control.Label
        Dispenser6CurrentSlider         matlab.ui.control.Slider
        TopDispenserBankLabel           matlab.ui.control.Label
        BottomDispenserBankLabel        matlab.ui.control.Label
        PumpsandgaugesTab               matlab.ui.container.Tab
        IonpumppressuretorrLabel        matlab.ui.control.Label
        IonPumpPressure                 matlab.ui.control.Label
        IongaugepressuretorrLabel       matlab.ui.control.Label
        IonGuagePressure                matlab.ui.control.Label
        IonpumpportopenLampLabel        matlab.ui.control.Label
        IonpumpportopenLamp             matlab.ui.control.Lamp
        IongaugeportopenLampLabel       matlab.ui.control.Label
        IongaugeportopenLamp            matlab.ui.control.Lamp
        IonGaugeOnButton                matlab.ui.control.StateButton
        ImagingTab                      matlab.ui.container.Tab
        FPGAChannelMappingTab           matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        CoincidencecounterTab           matlab.ui.container.Tab
        DataLoggingTab                  matlab.ui.container.Tab
        CurrentdatafolderLabel          matlab.ui.control.Label
        OpenfolderinexplorerButton      matlab.ui.control.Button
        BackupstatusLampLabel           matlab.ui.control.Label
        BackupstatusLamp                matlab.ui.control.Lamp
        CamerasTab                      matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        StartfeedButton                 matlab.ui.control.Button
        StopfeedButton                  matlab.ui.control.Button
        GrabImageButton                 matlab.ui.control.Button
        TimelapsesettingButton          matlab.ui.control.Button
        UtilitiesTab                    matlab.ui.container.Tab
        SteckRb87NumbersButton          matlab.ui.control.Button
        GaussianCalculatorButton        matlab.ui.control.Button
        PSU1WebcontrolButton            matlab.ui.control.Button
        PSU2WebcontrolButton            matlab.ui.control.Button
        ICEGUIButton                    matlab.ui.control.Button
        MSquaredWebControlButton        matlab.ui.control.Button
        PairInteractionCalculatorAppButton  matlab.ui.control.Button
        XKCDButton                      matlab.ui.control.Button
        ColdRydbergAtomExperimentControlCenterLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LoadMOTButton                   matlab.ui.control.Button
        EmergencyOFFButton              matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        ResetButton                     matlab.ui.control.Button
        SystemStatusLampLabel           matlab.ui.control.Label
        SystemStatusLamp                matlab.ui.control.Lamp
        GrabImagesButton                matlab.ui.control.Button
        ShutdownButton                  matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.ICEserial=serial('COM4');            
        end

        % Value changed function: MOTButton
        function MOTButtonValueChanged(app, event)
            value = app.MOTButton.Value;
            
        end

        % Button pushed function: SteckRb87NumbersButton
        function SteckRb87NumbersButtonPushed(app, event)
            web('http://steck.us/alkalidata/rubidium87numbers.1.6.pdf','-browser');
        end

        % Button pushed function: XKCDButton
        function XKCDButtonPushed(app, event)
            web('http://www.xkcd.com/','-browser');
        end

        % Button pushed function: PSU1WebcontrolButton
        function PSU1WebcontrolButtonPushed(app, event)
             web('http://10.10.10.102','-browser');
        end

        % Button pushed function: PSU2WebcontrolButton
        function PSU2WebcontrolButtonPushed(app, event)
            web('http://10.10.10.103','-browser');
        end

        % Button pushed function: MSquaredWebControlButton
        function MSquaredWebControlButtonPushed(app, event)
             web('http://10.10.10.105','-browser');
        end

        % Button pushed function: SanityCheckButton
        function SanityCheckButtonPushed(app, event)
            prog=CodeGenerator;
            pulseSeq={Pulse('DigOut0',0,5e6),Pulse('DigOut1',0,4e6)}; 
            prog.GenSeq(pulseSeq);
            prog.GenFinish;
            statString{1}=[datestr(now,'HH:MM:SS') ' Sequence Generated'];
            app.FPGAConnectionStatusBox.Value=statString;
            
            status=uploadCode(prog);
            statString{2}=[datestr(now,'HH:MM:SS') ' Upload started'];
            app.FPGAConnectionStatusBox.Value=statString;
            if status==1
                app.FPGAuploadIndicatorLamp.Color = 'red';
                statString{3}=[datestr(now,'HH:MM:SS') ' Upload complete'];
                app.FPGAConnectionStatusBox.Value=statString;
                
            else 
                app.FPGAuploadIndicatorLamp.Color = 'red';
                statString{3}=[datestr(now,'HH:MM:SS') 'upload failed!'];
                app.FPGAConnectionStatusBox.Value=statString;
            end


        end

        % Button pushed function: LoadSequenceButton
        function LoadSequenceButtonPushed(app, event)
            seqLoader();
        end

        % Value changed function: TAONButton
        function TAONButtonValueChanged(app, event)
            value = app.TAONButton.Value;
            if (value==1)
            prog=CodeGenerator;
            
            prog.GenSeq({Pulse('DigOut0',0,0)}); 
            prog.GenFinish;    
            else
            prog=CodeGenerator;
            prog.GenSeq({Pulse('DigOut0',0,-1)}); 
            prog.GenFinish;    
            end
            uploadCode(prog)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WISColdRydbergUIFigure = uifigure;
            app.WISColdRydbergUIFigure.Name = 'WISColdRydberg';
            setAutoResize(app, app.WISColdRydbergUIFigure, true)
            app.TabGroup = uitabgroup(app.WISColdRydbergUIFigure);
            app.LasersandAOMsTab = uitab(app.TabGroup);
            app.LasersandAOMsTab.Title = 'Lasers and AOMs';
            app.LasersandAOMsTab.Units = 'pixels';
            app.MOTButton = uibutton(app.LasersandAOMsTab, 'state');
            app.MOTButton.ValueChangedFcn = createCallbackFcn(app, @MOTButtonValueChanged, true);
            app.MOTButton.Text = 'MOT';
            app.RepumpButton = uibutton(app.LasersandAOMsTab, 'state');
            app.RepumpButton.Text = 'Repump';
            app.DepumpButton = uibutton(app.LasersandAOMsTab, 'state');
            app.DepumpButton.Text = 'Depump';
            app.MOT1PowermWGaugeLabel = uilabel(app.LasersandAOMsTab);
            app.MOT1PowermWGaugeLabel.Text = 'MOT 1 Power [mW]';
            app.MOT1PowermWGauge = uigauge(app.LasersandAOMsTab, 'circular');
            app.MOT2PowermWGaugeLabel = uilabel(app.LasersandAOMsTab);
            app.MOT2PowermWGaugeLabel.Text = 'MOT 2 Power [mW]';
            app.MOT2PowermWGauge = uigauge(app.LasersandAOMsTab, 'circular');
            app.MOT3PowermWGaugeLabel = uilabel(app.LasersandAOMsTab);
            app.MOT3PowermWGaugeLabel.Text = 'MOT 3 Power [mW]';
            app.MOT3PowermWGauge = uigauge(app.LasersandAOMsTab, 'circular');
            app.ControlButton = uibutton(app.LasersandAOMsTab, 'state');
            app.ControlButton.Text = 'Control';
            app.ProbeButton = uibutton(app.LasersandAOMsTab, 'state');
            app.ProbeButton.Text = 'Probe';
            app.UITable2 = uitable(app.LasersandAOMsTab);
            app.UITable2.ColumnName = {'Laser'; 'Wavelength'};
            app.UITable2.RowName = {};
            app.AOM1VCOFreqSliderLabel = uilabel(app.LasersandAOMsTab);
            app.AOM1VCOFreqSliderLabel.Text = 'AOM 1 VCO Freq';
            app.AOM1VCOFreqSlider = uislider(app.LasersandAOMsTab);
            app.AOM2VCOFreqSliderLabel = uilabel(app.LasersandAOMsTab);
            app.AOM2VCOFreqSliderLabel.Text = 'AOM 2 VCO Freq';
            app.AOM2VCOFreqSlider = uislider(app.LasersandAOMsTab);
            app.AOM3VCOFreqSliderLabel = uilabel(app.LasersandAOMsTab);
            app.AOM3VCOFreqSliderLabel.Text = 'AOM 3 VCO Freq';
            app.AOM3VCOFreqSlider = uislider(app.LasersandAOMsTab);
            app.AOM1TTLSwitchLabel = uilabel(app.LasersandAOMsTab);
            app.AOM1TTLSwitchLabel.Text = 'AOM 1 TTL ';
            app.AOM1TTLSwitch = uiswitch(app.LasersandAOMsTab, 'slider');
            app.AOM2TTLSwitchLabel = uilabel(app.LasersandAOMsTab);
            app.AOM2TTLSwitchLabel.Text = 'AOM 2 TTL ';
            app.AOM2TTLSwitch = uiswitch(app.LasersandAOMsTab, 'slider');
            app.AOM3TTLSwitchLabel = uilabel(app.LasersandAOMsTab);
            app.AOM3TTLSwitchLabel.Text = 'AOM 3 TTL ';
            app.AOM3TTLSwitch = uiswitch(app.LasersandAOMsTab, 'slider');
            app.AOM1freq = uilabel(app.LasersandAOMsTab);
            app.AOM1freq.Text = '0 MHz';
            app.AOM2freq = uilabel(app.LasersandAOMsTab);
            app.AOM2freq.Text = '0 MHz';
            app.AOM3freq = uilabel(app.LasersandAOMsTab);
            app.AOM3freq.Text = '0 MHz';
            app.DipoleTrapButton = uibutton(app.LasersandAOMsTab, 'state');
            app.DipoleTrapButton.Text = 'Dipole Trap';
            app.TAONButton = uibutton(app.LasersandAOMsTab, 'state');
            app.TAONButton.ValueChangedFcn = createCallbackFcn(app, @TAONButtonValueChanged, true);
            app.TAONButton.Text = 'TA ON';
            app.ICEonlineLamp_2Label = uilabel(app.LasersandAOMsTab);
            app.ICEonlineLamp_2Label.Text = 'ICE online';
            app.ICEonlineLamp_2 = uilamp(app.LasersandAOMsTab);
            app.ElectromagnetsTab = uitab(app.TabGroup);
            app.ElectromagnetsTab.Title = 'Electromagnets';
            app.ElectromagnetsTab.Units = 'pixels';
            app.CircularCoilCurrentSliderLabel = uilabel(app.ElectromagnetsTab);
            app.CircularCoilCurrentSliderLabel.Text = 'Circular Coil Current (A)';
            app.CircularCoilCurrentSlider = uislider(app.ElectromagnetsTab);
            app.CircularCoilCurrentSlider.Limits = [0 220];
            app.RectangularCoilCurrentSliderLabel = uilabel(app.ElectromagnetsTab);
            app.RectangularCoilCurrentSliderLabel.Text = 'Rectangular Coil Current (A)';
            app.RectangularCoilCurrentSlider = uislider(app.ElectromagnetsTab);
            app.RectangularCoilCurrentSlider.Limits = [0 220];
            app.CicularCoilOffSwitchLabel = uilabel(app.ElectromagnetsTab);
            app.CicularCoilOffSwitchLabel.Text = 'Cicular Coil Off';
            app.CicularCoilOffSwitch = uiswitch(app.ElectromagnetsTab, 'slider');
            app.RectangularCoilOffSwitchLabel = uilabel(app.ElectromagnetsTab);
            app.RectangularCoilOffSwitchLabel.Text = 'Rectangular Coil Off';
            app.RectangularCoilOffSwitch = uiswitch(app.ElectromagnetsTab, 'slider');
            app.Snubberchannel1SwitchLabel = uilabel(app.ElectromagnetsTab);
            app.Snubberchannel1SwitchLabel.Text = 'Snubber channel1';
            app.Snubberchannel1Switch = uiswitch(app.ElectromagnetsTab, 'toggle');
            app.Snubberchannel2SwitchLabel = uilabel(app.ElectromagnetsTab);
            app.Snubberchannel2SwitchLabel.Text = 'Snubber channel 2';
            app.Snubberchannel2Switch = uiswitch(app.ElectromagnetsTab, 'toggle');
            app.BiascoilpairXcurrentLabel = uilabel(app.ElectromagnetsTab);
            app.BiascoilpairXcurrentLabel.Text = 'Bias coil pair X current';
            app.CircularCoilCurrentSlider_2 = uislider(app.ElectromagnetsTab);
            app.CircularCoilCurrentSlider_2.Limits = [0 10];
            app.BiascoilpairYcurrentLabel = uilabel(app.ElectromagnetsTab);
            app.BiascoilpairYcurrentLabel.Text = 'Bias coil pair Y current';
            app.CircularCoilCurrentSlider_3 = uislider(app.ElectromagnetsTab);
            app.CircularCoilCurrentSlider_3.Limits = [0 10];
            app.BiascoilpairZcurrentLabel = uilabel(app.ElectromagnetsTab);
            app.BiascoilpairZcurrentLabel.Text = 'Bias coil pair Z current';
            app.CircularCoilCurrentSlider_4 = uislider(app.ElectromagnetsTab);
            app.CircularCoilCurrentSlider_4.Limits = [0 10];
            app.SequenceBuilderTab = uitab(app.TabGroup);
            app.SequenceBuilderTab.Title = 'Sequence Builder';
            app.SequenceBuilderTab.Units = 'pixels';
            app.SequenceListBox = uilistbox(app.SequenceBuilderTab);
            app.SequenceListBox.Items = {'Sequence item 1'};
            app.SequenceListBox.Value = 'Sequence item 1';
            app.ClearallButton = uibutton(app.SequenceBuilderTab, 'push');
            app.ClearallButton.Text = 'Clear all';
            app.LoadSequenceButton = uibutton(app.SequenceBuilderTab, 'push');
            app.LoadSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSequenceButtonPushed, true);
            app.LoadSequenceButton.Text = 'Load Sequence';
            app.SaveSequenceButton = uibutton(app.SequenceBuilderTab, 'push');
            app.SaveSequenceButton.Text = 'Save Sequence';
            app.RunButton = uibutton(app.SequenceBuilderTab, 'push');
            app.RunButton.Text = 'Run';
            app.AddpulseButton = uibutton(app.SequenceBuilderTab, 'push');
            app.AddpulseButton.Text = 'Add pulse...';
            app.SanityCheckButton = uibutton(app.SequenceBuilderTab, 'push');
            app.SanityCheckButton.ButtonPushedFcn = createCallbackFcn(app, @SanityCheckButtonPushed, true);
            app.SanityCheckButton.Text = 'Sanity Check ';
            app.FPGAstatusLabel = uilabel(app.SequenceBuilderTab);
            app.FPGAstatusLabel.Text = 'FPGA status';
            app.FPGAConnectionStatusBox = uitextarea(app.SequenceBuilderTab);
            app.FPGAConnectionStatusBox.Editable = 'off';
            app.FPGAuploadIndicatorLamp = uilamp(app.SequenceBuilderTab);
            app.StatusTab = uitab(app.TabGroup);
            app.StatusTab.Title = 'Status';
            app.StatusTab.Units = 'pixels';
            app.CoilcoolingwaterflowLampLabel = uilabel(app.StatusTab);
            app.CoilcoolingwaterflowLampLabel.Text = 'Coil cooling water flow';
            app.CoilcoolingwaterflowLamp = uilamp(app.StatusTab);
            app.CoilsupplystatusLampLabel = uilabel(app.StatusTab);
            app.CoilsupplystatusLampLabel.Text = 'Coil supply status';
            app.CoilsupplystatusLamp = uilamp(app.StatusTab);
            app.coiltemperatureOKLampLabel = uilabel(app.StatusTab);
            app.coiltemperatureOKLampLabel.Text = 'coil temperature OK';
            app.coiltemperatureOKLamp = uilamp(app.StatusTab);
            app.TAONLampLabel = uilabel(app.StatusTab);
            app.TAONLampLabel.Text = 'TA ON';
            app.TAONLamp = uilamp(app.StatusTab);
            app.MasterLockedLampLabel = uilabel(app.StatusTab);
            app.MasterLockedLampLabel.Text = 'Master Locked';
            app.MasterLockedLamp = uilamp(app.StatusTab);
            app.MOTLaserLOCKEDLampLabel = uilabel(app.StatusTab);
            app.MOTLaserLOCKEDLampLabel.Text = 'MOT Laser LOCKED';
            app.MOTLaserLOCKEDLamp = uilamp(app.StatusTab);
            app.RepumpLaserLOCKEDLampLabel = uilabel(app.StatusTab);
            app.RepumpLaserLOCKEDLampLabel.Text = 'Repump Laser LOCKED';
            app.RepumpLaserLOCKEDLamp = uilamp(app.StatusTab);
            app.DepumpLaserLOCKEDLampLabel = uilabel(app.StatusTab);
            app.DepumpLaserLOCKEDLampLabel.Text = 'Depump Laser LOCKED';
            app.DepumpLaserLOCKEDLamp = uilamp(app.StatusTab);
            app.ImagingLaserLOCKEDLampLabel = uilabel(app.StatusTab);
            app.ImagingLaserLOCKEDLampLabel.Text = 'Imaging Laser LOCKED';
            app.ImagingLaserLOCKEDLamp = uilamp(app.StatusTab);
            app.TiSaphLOCKEDLampLabel = uilabel(app.StatusTab);
            app.TiSaphLOCKEDLampLabel.Text = 'Ti:Saph LOCKED';
            app.TiSaphLOCKEDLamp = uilamp(app.StatusTab);
            app.CombonlineLampLabel = uilabel(app.StatusTab);
            app.CombonlineLampLabel.Text = 'Comb online';
            app.CombonlineLamp = uilamp(app.StatusTab);
            app.StatusmonitorarduinoConnectedLampLabel = uilabel(app.StatusTab);
            app.StatusmonitorarduinoConnectedLampLabel.Text = 'Status monitor arduino Connected';
            app.StatusmonitorarduinoConnectedLamp = uilamp(app.StatusTab);
            app.WavelengthmeteronlineLampLabel = uilabel(app.StatusTab);
            app.WavelengthmeteronlineLampLabel.Text = 'Wavelength meter online';
            app.WavelengthmeteronlineLamp = uilamp(app.StatusTab);
            app.ConfigureerrorreportingButton = uibutton(app.StatusTab, 'push');
            app.ConfigureerrorreportingButton.Text = 'Configure error reporting';
            app.ICEonlineLampLabel = uilabel(app.StatusTab);
            app.ICEonlineLampLabel.Text = 'ICE online';
            app.ICEonlineLamp = uilamp(app.StatusTab);
            app.TiSaphOnlineLampLabel = uilabel(app.StatusTab);
            app.TiSaphOnlineLampLabel.Text = 'Ti:Saph Online';
            app.TiSaphOnlineLamp = uilamp(app.StatusTab);
            app.TATempcontrolleronLampLabel = uilabel(app.StatusTab);
            app.TATempcontrolleronLampLabel.Text = 'TA Temp controller on';
            app.TATempcontrolleronLamp = uilamp(app.StatusTab);
            app.DBR1TempLockedLampLabel = uilabel(app.StatusTab);
            app.DBR1TempLockedLampLabel.Text = 'DBR 1 Temp Locked';
            app.DBR1TempLockedLamp = uilamp(app.StatusTab);
            app.DispensersTab = uitab(app.TabGroup);
            app.DispensersTab.Title = 'Dispensers';
            app.DispensersTab.Units = 'pixels';
            app.Dispenser1CurrentSliderLabel = uilabel(app.DispensersTab);
            app.Dispenser1CurrentSliderLabel.Text = 'Dispenser 1 Current';
            app.Dispenser1CurrentSlider = uislider(app.DispensersTab);
            app.Dispenser1CurrentSlider.Limits = [0 7];
            app.Dispenser2CurrentSliderLabel = uilabel(app.DispensersTab);
            app.Dispenser2CurrentSliderLabel.Text = 'Dispenser 2 Current';
            app.Dispenser2CurrentSlider = uislider(app.DispensersTab);
            app.Dispenser2CurrentSlider.Limits = [0 7];
            app.Dispenser3CurrentSliderLabel = uilabel(app.DispensersTab);
            app.Dispenser3CurrentSliderLabel.Text = 'Dispenser 3 Current';
            app.Dispenser3CurrentSlider = uislider(app.DispensersTab);
            app.Dispenser3CurrentSlider.Limits = [0 7];
            app.Dispenser4CurrentSliderLabel = uilabel(app.DispensersTab);
            app.Dispenser4CurrentSliderLabel.Text = 'Dispenser 4 Current';
            app.Dispenser4CurrentSlider = uislider(app.DispensersTab);
            app.Dispenser4CurrentSlider.Limits = [0 7];
            app.Dispenser5CurrentSliderLabel = uilabel(app.DispensersTab);
            app.Dispenser5CurrentSliderLabel.Text = 'Dispenser 5 Current';
            app.Dispenser5CurrentSlider = uislider(app.DispensersTab);
            app.Dispenser5CurrentSlider.Limits = [0 7];
            app.Dispenser6CurrentSliderLabel = uilabel(app.DispensersTab);
            app.Dispenser6CurrentSliderLabel.Text = 'Dispenser 6 Current';
            app.Dispenser6CurrentSlider = uislider(app.DispensersTab);
            app.Dispenser6CurrentSlider.Limits = [0 7];
            app.TopDispenserBankLabel = uilabel(app.DispensersTab);
            app.TopDispenserBankLabel.Text = 'Top Dispenser Bank ';
            app.BottomDispenserBankLabel = uilabel(app.DispensersTab);
            app.BottomDispenserBankLabel.Text = 'Bottom Dispenser Bank ';
            app.PumpsandgaugesTab = uitab(app.TabGroup);
            app.PumpsandgaugesTab.Title = 'Pumps and gauges';
            app.PumpsandgaugesTab.Units = 'pixels';
            app.IonpumppressuretorrLabel = uilabel(app.PumpsandgaugesTab);
            app.IonpumppressuretorrLabel.Text = 'Ion pump pressure [torr]:';
            app.IonPumpPressure = uilabel(app.PumpsandgaugesTab);
            app.IonPumpPressure.Text = '0';
            app.IongaugepressuretorrLabel = uilabel(app.PumpsandgaugesTab);
            app.IongaugepressuretorrLabel.Text = 'Ion gauge pressure [torr]:';
            app.IonGuagePressure = uilabel(app.PumpsandgaugesTab);
            app.IonGuagePressure.Text = '0';
            app.IonpumpportopenLampLabel = uilabel(app.PumpsandgaugesTab);
            app.IonpumpportopenLampLabel.Text = 'Ion pump port open';
            app.IonpumpportopenLamp = uilamp(app.PumpsandgaugesTab);
            app.IongaugeportopenLampLabel = uilabel(app.PumpsandgaugesTab);
            app.IongaugeportopenLampLabel.Text = 'Ion gauge port open';
            app.IongaugeportopenLamp = uilamp(app.PumpsandgaugesTab);
            app.IonGaugeOnButton = uibutton(app.PumpsandgaugesTab, 'state');
            app.IonGaugeOnButton.Text = 'Ion Gauge On';
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.ImagingTab.Units = 'pixels';
            app.FPGAChannelMappingTab = uitab(app.TabGroup);
            app.FPGAChannelMappingTab.Title = 'FPGA Channel Mapping';
            app.FPGAChannelMappingTab.Units = 'pixels';
            app.UITable = uitable(app.FPGAChannelMappingTab);
            app.UITable.ColumnName = {'Card / Connector'; 'Hardware name'; 'software name'; 'discription'};
            app.UITable.RowName = {};
            app.CoincidencecounterTab = uitab(app.TabGroup);
            app.CoincidencecounterTab.Title = 'Coincidence counter';
            app.CoincidencecounterTab.Units = 'pixels';
            app.DataLoggingTab = uitab(app.TabGroup);
            app.DataLoggingTab.Title = 'Data Logging';
            app.DataLoggingTab.Units = 'pixels';
            app.CurrentdatafolderLabel = uilabel(app.DataLoggingTab);
            app.CurrentdatafolderLabel.Text = 'Current data folder:';
            app.OpenfolderinexplorerButton = uibutton(app.DataLoggingTab, 'push');
            app.OpenfolderinexplorerButton.Text = 'Open folder in explorer';
            app.BackupstatusLampLabel = uilabel(app.DataLoggingTab);
            app.BackupstatusLampLabel.Text = 'Backup status';
            app.BackupstatusLamp = uilamp(app.DataLoggingTab);
            app.CamerasTab = uitab(app.TabGroup);
            app.CamerasTab.Title = 'Cameras';
            app.CamerasTab.Units = 'pixels';
            app.UIAxes3 = uiaxes(app.CamerasTab);
            title(app.UIAxes3, 'Live Feed');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.StartfeedButton = uibutton(app.CamerasTab, 'push');
            app.StartfeedButton.Text = 'Start feed';
            app.StopfeedButton = uibutton(app.CamerasTab, 'push');
            app.StopfeedButton.Text = 'Stop feed';
            app.GrabImageButton = uibutton(app.CamerasTab, 'push');
            app.GrabImageButton.Text = 'Grab Image';
            app.TimelapsesettingButton = uibutton(app.CamerasTab, 'push');
            app.TimelapsesettingButton.Text = 'Timelapse setting...';
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.UtilitiesTab.Units = 'pixels';
            app.SteckRb87NumbersButton = uibutton(app.UtilitiesTab, 'push');
            app.SteckRb87NumbersButton.ButtonPushedFcn = createCallbackFcn(app, @SteckRb87NumbersButtonPushed, true);
            app.SteckRb87NumbersButton.Text = 'Steck Rb87 Numbers';
            app.GaussianCalculatorButton = uibutton(app.UtilitiesTab, 'push');
            app.GaussianCalculatorButton.Text = 'Gaussian Calculator';
            app.PSU1WebcontrolButton = uibutton(app.UtilitiesTab, 'push');
            app.PSU1WebcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @PSU1WebcontrolButtonPushed, true);
            app.PSU1WebcontrolButton.Text = 'PSU1 Web control';
            app.PSU2WebcontrolButton = uibutton(app.UtilitiesTab, 'push');
            app.PSU2WebcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @PSU2WebcontrolButtonPushed, true);
            app.PSU2WebcontrolButton.Text = 'PSU2 Web control';
            app.ICEGUIButton = uibutton(app.UtilitiesTab, 'push');
            app.ICEGUIButton.Text = 'ICE GUI';
            app.MSquaredWebControlButton = uibutton(app.UtilitiesTab, 'push');
            app.MSquaredWebControlButton.ButtonPushedFcn = createCallbackFcn(app, @MSquaredWebControlButtonPushed, true);
            app.MSquaredWebControlButton.Text = 'MSquared Web Control';
            app.PairInteractionCalculatorAppButton = uibutton(app.UtilitiesTab, 'push');
            app.PairInteractionCalculatorAppButton.Text = 'Pair Interaction Calculator App';
            app.XKCDButton = uibutton(app.UtilitiesTab, 'push');
            app.XKCDButton.ButtonPushedFcn = createCallbackFcn(app, @XKCDButtonPushed, true);
            app.XKCDButton.Text = 'XKCD';
            app.ColdRydbergAtomExperimentControlCenterLabel = uilabel(app.WISColdRydbergUIFigure);
            app.ColdRydbergAtomExperimentControlCenterLabel.Text = 'Cold Rydberg Atom Experiment Control Center';
            app.UIAxes = uiaxes(app.WISColdRydbergUIFigure);
            title(app.UIAxes, 'Cloud front');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.LoadMOTButton = uibutton(app.WISColdRydbergUIFigure, 'push');
            app.LoadMOTButton.Text = 'Load MOT';
            app.EmergencyOFFButton = uibutton(app.WISColdRydbergUIFigure, 'push');
            app.EmergencyOFFButton.Text = 'Emergency OFF';
            app.UIAxes_2 = uiaxes(app.WISColdRydbergUIFigure);
            title(app.UIAxes_2, 'Cloud side');
            xlabel(app.UIAxes_2, 'X');
            ylabel(app.UIAxes_2, 'Y');
            app.ResetButton = uibutton(app.WISColdRydbergUIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.SystemStatusLampLabel = uilabel(app.WISColdRydbergUIFigure);
            app.SystemStatusLampLabel.Text = 'System Status';
            app.SystemStatusLamp = uilamp(app.WISColdRydbergUIFigure);
            app.GrabImagesButton = uibutton(app.WISColdRydbergUIFigure, 'push');
            app.GrabImagesButton.Text = 'Grab Images';
            app.ShutdownButton = uibutton(app.WISColdRydbergUIFigure, 'push');
            app.ShutdownButton.Text = 'Shutdown';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ControlGui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WISColdRydbergUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WISColdRydbergUIFigure)
        end
    end
end